classdef thrust_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ThrustSliderLabel  matlab.ui.control.Label
        ThrustSlider       matlab.ui.control.Slider
        Label              matlab.ui.control.Label
        ThrustkgLabel      matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       u = udpport("IPV4", 'LocalHost', "0.0.0.0", 'LocalPort', 8000);
       myTimer;
    end
    
    methods (Access = private)
        
        function Update(app,~,~)
            data = read(app.u,3,"string"); 
            flush(app.u,"input");
            app.Label.Text = data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myTimer = timer();
            app.myTimer.Period = 1;
            app.myTimer.ExecutionMode = 'fixedRate';
            app.myTimer.TimerFcn = @app.Update; 
            start(app.myTimer);
        end

        % Value changed function: ThrustSlider
        function ThrustSliderValueChanged(app, event)
            thrust = app.ThrustSlider.Value;
            write(app.u,string(thrust),"192.168.1.102",8888)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThrustSliderLabel = uilabel(app.UIFigure);
            app.ThrustSliderLabel.Text = 'Thrust';
            app.ThrustSlider = uislider(app.UIFigure);
            app.ThrustSlider.ValueChangedFcn = createCallbackFcn(app, @ThrustSliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.ThrustkgLabel = uilabel(app.UIFigure);
            app.ThrustkgLabel.Text = 'Thrust (kg):';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Thrust (g)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thrust_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end